classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BeginningSliderLabel  matlab.ui.control.Label
        BeginningSlider       matlab.ui.control.Slider
        EndSliderLabel        matlab.ui.control.Label
        EndSlider             matlab.ui.control.Slider
        ChoosefolderButton    matlab.ui.control.Button
        AxesFullData          matlab.ui.control.UIAxes
        AxesChosenData        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filenamesToRead % Description
        sFolderPath % Description
        val % Description
        times % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clc
            %app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            %app.filenamesToRead
        end

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            app.sFolderPath='/home/jacek/Dokumenty/Matlab/Testy LEM MotoPark/lem_logi_motopark_30102020/20201030_092123662678_decoded'; %for quick testing only
            if(exist(app.sFolderPath))
                app.sFolderPath=uigetdir(app.sFolderPath);
            else
                app.sFolderPath=uigetdir();
            end
            %sFolderPath=uigetdir()
            app.filenamesToRead
            app.filenamesToRead = ["_TEL_HVBMS_CURR.txt", "_TEL_HVBMS_MAXCVOLT.txt", "_TEL_HVBMS_MINCVOLT.txt"]
            [oTimestamps, times, val] = readSensorsInFolder(app.filenamesToRead, app.sFolderPath);
            app.val=val;
            app.times=times;
            plot(app.AxesFullData, times, val(:,1))
            app.BeginningSlider.Limits = [1,length(oTimestamps)]
            app.EndSlider.Limits = [1,length(oTimestamps)]
            app.EndSlider.Value = length(oTimestamps)
        end

        % Value changed function: EndSlider
        function EndSliderValueChanged(app, event)
            value = app.EndSlider.Value;
            plot(app.AxesChosenData, app.times(app.BeginningSlider.Value:app.EndSlider.Value), app.val(app.BeginningSlider.Value:app.EndSlider.Value,1))
        end

        % Value changed function: BeginningSlider
        function BeginningSliderValueChanged(app, event)
            value = app.BeginningSlider.Value;
            plot(app.AxesChosenData, app.times(app.BeginningSlider.Value:app.EndSlider.Value), app.val(app.BeginningSlider.Value:app.EndSlider.Value,1))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BeginningSliderLabel = uilabel(app.UIFigure);
            app.BeginningSliderLabel.Text = 'Beginning';
            app.BeginningSlider = uislider(app.UIFigure);
            app.BeginningSlider.ValueChangedFcn = createCallbackFcn(app, @BeginningSliderValueChanged, true);
            app.EndSliderLabel = uilabel(app.UIFigure);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.UIFigure);
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @EndSliderValueChanged, true);
            app.ChoosefolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.AxesFullData = uiaxes(app.UIFigure);
            title(app.AxesFullData, 'Title')
            xlabel(app.AxesFullData, 'X')
            ylabel(app.AxesFullData, 'Y')
            zlabel(app.AxesFullData, 'Z')
            app.AxesChosenData = uiaxes(app.UIFigure);
            title(app.AxesChosenData, 'Title')
            xlabel(app.AxesChosenData, 'X')
            ylabel(app.AxesChosenData, 'Y')
            zlabel(app.AxesChosenData, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end